classdef hot_plate_stirrer_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TemperatureGaugeLabel  matlab.ui.control.Label
        TemperatureGauge       matlab.ui.control.SemicircularGauge
        Knob                   matlab.ui.control.Knob
        UIAxes                 matlab.ui.control.UIAxes
        HeaterSwitchLabel      matlab.ui.control.Label
        HeaterSwitch           matlab.ui.control.Switch
        StirrerSwitchLabel     matlab.ui.control.Label
        StirrerSwitch          matlab.ui.control.Switch
    end


    properties (Access = public)
        s
        A = zeros(300, 1)
        t = zeros(300, 1)
        setTempV = zeros(300, 1)   %the set temperature vector
        total_A = 0
        total_t = 0
        selection
        ok
        COM_ports = seriallist;
        setTemp = 50
        stirrer_heater %specifies wether the switches are on or off
        stirrer = true
        heater = true
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            [app.selection,app.ok] = listdlg('PromptString','Select a COM port',...
            'SelectionMode','single',...
            'ListString',seriallist);
            app.s = serial(app.COM_ports(app.selection), 'baudrate', 57600);
            
            if (app.s.Status == 'closed')
                fopen(app.s);
            end
            hold(app.UIAxes);
            tic
            while 1
                
                for v = 1:300
                    if ~app.stirrer && ~app.heater
                        app.stirrer_heater = 0;
                    elseif app.stirrer && ~app.heater
                        app.stirrer_heater = 1;
                    elseif ~app.stirrer && app.heater
                        app.stirrer_heater = 2;
                    elseif app.stirrer && app.heater
                        app.stirrer_heater = 3;
                    end
                    app.A(v) = fread(app.s, 1, 'uint16')/100;
                    fwrite(app.s, app.setTemp, 'uint8')
                    fwrite(app.s, app.stirrer_heater, 'uint8')
                    app.t(v) = toc;
                    app.setTempV(v) = app.setTemp;
                    app.TemperatureGauge.Value = app.A(v);
                    plot(app.UIAxes, app.t(1:v), app.A(1:v), 'b-','LineWidth',2);
                    plot(app.UIAxes,app.t(1:v), app.setTempV(1:v), 'g', 'LineWidth', 2);
                    legend(app.UIAxes, 'Current temperature', 'Reference temperature');
                    %plot(app.UIAxes, time, app.A);
                    pause(0.1)
                    
                end
                app.total_A = [;app.A];
                app.total_t = [;app.t];
                app.A = zeros(300, 1);
                app.t = zeros(300, 1);
                app.setTempV = zeros(300, 1);
            end
        end

        % Value changed function: HeaterSwitch
        function HeaterSwitchValueChanged(app, event)
            if(strcmp(app.HeaterSwitch.Value, 'On'))
                app.heater = true;
            else
                app.heater = false;
            end
            
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            app.setTemp = event.Value;
            
        end

        % Value changed function: StirrerSwitch
        function StirrerSwitchValueChanged(app, event)
            if(strcmp(app.StirrerSwitch.Value, 'On'))
                app.stirrer = true;
            else
                app.stirrer = false;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %if(exist('s', 'var'))
                fclose(app.s);
            %end
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureGaugeLabel.Text = 'TemperatureGauge';
            app.TemperatureGauge = uigauge(app.UIFigure, 'semicircular');
            app.TemperatureGauge.Limits = [0 250];
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [25 200];
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.Value = 50;
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature')
            app.UIAxes.YLim = [0 250];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLim = [0 1];
            app.UIAxes.CLimMode = 'manual';
            app.UIAxes.YTick = [0 50 100 150 200 250];
            app.HeaterSwitchLabel = uilabel(app.UIFigure);
            app.HeaterSwitchLabel.Text = 'Heater';
            app.HeaterSwitch = uiswitch(app.UIFigure, 'slider');
            app.HeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @HeaterSwitchValueChanged, true);
            app.HeaterSwitch.Value = 'On';
            app.StirrerSwitchLabel = uilabel(app.UIFigure);
            app.StirrerSwitchLabel.Text = 'Stirrer';
            app.StirrerSwitch = uiswitch(app.UIFigure, 'slider');
            app.StirrerSwitch.ValueChangedFcn = createCallbackFcn(app, @StirrerSwitchValueChanged, true);
            app.StirrerSwitch.Value = 'On';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hot_plate_stirrer_5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end